classdef Login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Label                    matlab.ui.control.Label
        Label_2                  matlab.ui.control.Label
        Image                    matlab.ui.control.Image
        Label_3                  matlab.ui.control.Label
        DentalCareLabel          matlab.ui.control.Label
        Image2_3                 matlab.ui.control.Image
        Image2_4                 matlab.ui.control.Image
        Image2_5                 matlab.ui.control.Image
        Image6                   matlab.ui.control.Image
        Label_4                  matlab.ui.control.Label
        Image6_2                 matlab.ui.control.Image
        dentalcaregmailcomLabel  matlab.ui.control.Label
        Image7                   matlab.ui.control.Image
        EMAILEditFieldLabel_2    matlab.ui.control.Label
        LoginButton              matlab.ui.control.Button
        Panel                    matlab.ui.container.Panel
        CommittedtoLabel         matlab.ui.control.Label
        dentalexcellenceLabel    matlab.ui.control.Label
        Image9                   matlab.ui.control.Image
        PkLabel                  matlab.ui.control.Label
        SignupButton             matlab.ui.control.Button
        NotauserSignupLabel      matlab.ui.control.Label
        HomeButton               matlab.ui.control.Button
        AboutButton              matlab.ui.control.Button
        PricingButton            matlab.ui.control.Button
        Image8                   matlab.ui.control.Image
        Image7_2                 matlab.ui.control.Image
        EMAIL                    matlab.ui.control.EditField
        PASSWORD                 matlab.ui.control.EditField
        LoginButton_2            matlab.ui.control.Button
        Image8_2                 matlab.ui.control.Image
        NotauserSignupLabel_2    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SignupButton
        function SignupButtonPushed(app, event)
            newguisignup;
        end

        % Callback function
        function NameEditFieldValueChanged(app, event)
            app.NameEditField.Enable;
            
        end

        % Button pushed function: LoginButton_2
        function LoginButton_2Pushed(app, event)
            conn=database('db','root','badmanner12');
            em=app.EMAIL.Value;
            em=num2str(em);
            
            sqlquery = ['SELECT * FROM registration where MOBILE =' em];
 result =fetch(conn,sqlquery);
password=result.Password;
pass=app.PASSWORD.Value;
 compare=strcmp(password,pass);
 
 if(compare==1)
  
 dashboard
 msgbox('Login Sucessfully')
    else
            warndlg('invalid user  name or psswoard');
 end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'pakistan-flag-icon-16.png';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.DentalCareLabel = uilabel(app.UIFigure);
            app.DentalCareLabel.Text = 'Dental Care';
            app.Image2_3 = uiimage(app.UIFigure);
            app.Image2_3.ImageSource = 'facebook.png';
            app.Image2_4 = uiimage(app.UIFigure);
            app.Image2_4.ImageSource = 'instagram.png';
            app.Image2_5 = uiimage(app.UIFigure);
            app.Image2_5.ImageSource = 'instagram.png';
            app.Image6 = uiimage(app.UIFigure);
            app.Image6.ImageSource = 'call.png';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '03312345678';
            app.Image6_2 = uiimage(app.UIFigure);
            app.Image6_2.ImageSource = 'mail.png';
            app.dentalcaregmailcomLabel = uilabel(app.UIFigure);
            app.dentalcaregmailcomLabel.Text = 'dentalcare@gmail.com';
            app.Image7 = uiimage(app.UIFigure);
            app.Image7.ImageSource = '923023-best-dentist-wallpapers-1920x1080.jpg';
            app.EMAILEditFieldLabel_2 = uilabel(app.UIFigure);
            app.EMAILEditFieldLabel_2.Enable = 'off';
            app.EMAILEditFieldLabel_2.Text = 'Password';
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.Text = 'Login';
            app.Panel = uipanel(app.UIFigure);
            app.CommittedtoLabel = uilabel(app.Panel);
            app.CommittedtoLabel.Enable = 'off';
            app.CommittedtoLabel.Text = 'Committed to ';
            app.dentalexcellenceLabel = uilabel(app.Panel);
            app.dentalexcellenceLabel.Enable = 'off';
            app.dentalexcellenceLabel.Text = 'dental excellence';
            app.Image9 = uiimage(app.Panel);
            app.Image9.ImageSource = 'Dental logo.png';
            app.PkLabel = uilabel(app.UIFigure);
            app.PkLabel.Text = 'Pk';
            app.SignupButton = uibutton(app.UIFigure, 'push');
            app.SignupButton.ButtonPushedFcn = createCallbackFcn(app, @SignupButtonPushed, true);
            app.SignupButton.Text = 'Signup';
            app.NotauserSignupLabel = uilabel(app.UIFigure);
            app.NotauserSignupLabel.Text = '*Not a user? Signup.';
            app.HomeButton = uibutton(app.UIFigure, 'push');
            app.HomeButton.Text = 'Home';
            app.AboutButton = uibutton(app.UIFigure, 'push');
            app.AboutButton.Text = 'About';
            app.PricingButton = uibutton(app.UIFigure, 'push');
            app.PricingButton.Text = 'Pricing';
            app.Image8 = uiimage(app.UIFigure);
            app.Image8.ImageSource = 'user.png';
            app.Image7_2 = uiimage(app.UIFigure);
            app.Image7_2.ImageSource = '923023-best-dentist-wallpapers-1920x1080.jpg';
            app.EMAIL = uieditfield(app.UIFigure, 'text');
            app.PASSWORD = uieditfield(app.UIFigure, 'text');
            app.LoginButton_2 = uibutton(app.UIFigure, 'push');
            app.LoginButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoginButton_2Pushed, true);
            app.LoginButton_2.Text = 'Login';
            app.Image8_2 = uiimage(app.UIFigure);
            app.Image8_2.ImageSource = 'user.png';
            app.NotauserSignupLabel_2 = uilabel(app.UIFigure);
            app.NotauserSignupLabel_2.Text = '*Not a user? Sign up.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end